classdef DataInladen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataGDTinladenButton           matlab.ui.control.Button
        BestandsnaamGDTEditField       matlab.ui.control.EditField
        BestandsnaamGDTEditFieldLabel  matlab.ui.control.Label
        BestandsnaamEDFEditField       matlab.ui.control.EditField
        BestandsnaamEDFEditFieldLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        StartMatlabEDFfileAnalyzerButton  matlab.ui.control.Button
        DataEDFinladenButton           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataedf % to hold EDF data
        infoedf % to hold EDF fileinfo
        fs % Sample Rate
 
    end
    
    methods (Access = private)
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function DataEDFInladenButtonValueChanged(app, event)

        end

        % Button pushed function: DataEDFinladenButton
        function DataEDFinladenButtonPushed(app, event)
            cla(app.UIAxes) % clear previous plots
            fileName = app.BestandsnaamEDFEditField.Value
            app.dataedf = edfread(fileName)
            app.infoedf = edfinfo(fileName)

            %Calculate sample rate:
            numSamples = app.infoedf.NumDataRecords;
            testDuration = app.infoedf.NumDataRecords;
            fs=numSamples/testDuration;

            %Show edf parameters
            textforconsole = sprintf("Patient: " + app.infoedf.Patient + "\nDate: " + app.infoedf.StartDate + "\nTime: " + app.infoedf.StartTime + "\nNumDataRecords: " + app.infoedf.NumDataRecords + "\nSample Rate: " + fs + " Hz")
            app.TextArea.Value = textforconsole
            

            %Plot PCO2, PCO2_DC, SPO2 en PR
            t=(0:numSamples-1)/fs;
            hold(app.UIAxes)

            y=app.dataedf.PCO2;
            plot(app.UIAxes,t,y)
                       
            y=app.dataedf.PCO2_DC;
            plot(app.UIAxes,t,y)
            
            y=app.dataedf.SpO2;
            plot(app.UIAxes,t,y)
            
            y=app.dataedf.PR;
            plot(app.UIAxes,t,y)
            
            legend(app.UIAxes,"PCO2","PCO2-DC","SpO2","PR")

        end

        % Callback function
        function EDFinfoButtonPushed(app, event)
            edfFileAnalyzer(app.dataedf)
        end

        % Button pushed function: StartMatlabEDFfileAnalyzerButton
        function TextAreaValueChanging(app, event)

        end

        % Value changed function: BestandsnaamEDFEditField
        function BestandsnaamEDFEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataEDFinladenButton = uibutton(app.UIFigure, 'push');
            app.DataEDFinladenButton.ButtonPushedFcn = createCallbackFcn(app, @DataEDFinladenButtonPushed, true);
            app.DataEDFinladenButton.Text = 'Data EDF inladen';
            app.StartMatlabEDFfileAnalyzerButton = uibutton(app.UIFigure, 'push');
            app.StartMatlabEDFfileAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.StartMatlabEDFfileAnalyzerButton.Text = 'Start Matlab EDFfileAnalyzer';
            app.TextArea = uitextarea(app.UIFigure);
            app.BestandsnaamEDFEditFieldLabel = uilabel(app.UIFigure);
            app.BestandsnaamEDFEditFieldLabel.Text = 'Bestandsnaam EDF';
            app.BestandsnaamEDFEditField = uieditfield(app.UIFigure, 'text');
            app.BestandsnaamEDFEditField.ValueChangedFcn = createCallbackFcn(app, @BestandsnaamEDFEditFieldValueChanged, true);
            app.BestandsnaamEDFEditField.Value = 'GD capno.edf';
            app.BestandsnaamGDTEditFieldLabel = uilabel(app.UIFigure);
            app.BestandsnaamGDTEditFieldLabel.Text = 'Bestandsnaam GDT';
            app.BestandsnaamGDTEditField = uieditfield(app.UIFigure, 'text');
            app.DataGDTinladenButton = uibutton(app.UIFigure, 'push');
            app.DataGDTinladenButton.Text = 'Data GDT inladen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataInladen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end